classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HitButton              matlab.ui.control.Button
        StandButton            matlab.ui.control.Button
        DeckBack               matlab.ui.control.Image
        DEALButton             matlab.ui.control.Button
        BalanceEditFieldLabel  matlab.ui.control.Label
        BalanceEditField       matlab.ui.control.NumericEditField
        BetEditFieldLabel      matlab.ui.control.Label
        BetEditField           matlab.ui.control.NumericEditField
        YouWonLabel            matlab.ui.control.Label
        YouLostLabel           matlab.ui.control.Label
        OutofMoneyLabel        matlab.ui.control.Label
        YouTiedLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        deck % Description
        xpos = 310;
        xpos2 = 310;
    end
    
    methods (Access = public)
            
        function check21(app, deck)
            total = app.sumHand(app.deck);
            while total>21
                for i=1:length(app.deck.p1Hand.cards)
                    if app.deck.p1Hand.cards(i).numVal == "11"
                        app.deck.p1Hand.cards(i).numVal = "1";
                        total = app.sumHand(app.deck);
                        break
                    end
                end
                if total <= 21
                    continue
                elseif total > 21
                    break
                end
            end
            if total > 21
                app.loseScreen
            elseif total == 21
                app.flipDealerHand(app.deck)
                boolDealer = app.checkDealerBJ;
                if boolDealer == true
                    if length(app.deck.p1Hand.cards) > 2
                        app.loseScreen
                    else
                        app.tieScreen
                    end
                elseif (boolDealer == false) && (length(app.deck.p1Hand.cards) > 2)
                    app.dealerDraw
                    app.winScreen
                end
            end
        end
        
        function total = sumHand(app, deck)
            sumArray = [];
            for i = 1:length(app.deck.p1Hand.cards)
                sumArray = [sumArray, str2double(app.deck.p1Hand.cards(i).numVal)];
            end
            app.deck.p1Hand.sumCards = sum(sumArray);
            total = app.deck.p1Hand.sumCards;
        end
        
        
        function total = sumDealer(app, deck)
            sumArray = [];
            for i = 1:length(app.deck.dealerHand.cards)
                sumArray = [sumArray, str2double(app.deck.dealerHand.cards(i).numVal)];
            end
            app.deck.dealerHand.sumCards = sum(sumArray);
            total = app.deck.dealerHand.sumCards;
        end
        
        function bool = checkDealerBJ(app, deck)
            value = str2double(app.deck.dealerHand.cards(1).numVal) + str2double(app.deck.dealerHand.cards(2).numVal);
            if value == 21
                bool = true;
            else
                bool = false;
            end
        end
        
        function dealerDraw(app, deck)
            total = app.sumDealer(app.deck);
            while total < 17
                app.deck.dealerHand.cards = [app.deck.dealerHand.cards, app.deck.cards(1)];
                app.deck.cards(1) = [];
                app.xpos2 = app.xpos2 - 37;
                app.deck.dealerHand.cards(end).privacy = 1;
                app.deck.dealerHand.cards(end).imageApp = app.dealerCard(app.deck, app.xpos2);
                total = app.sumDealer(app.deck);
                if total > 21
                    while total > 21
                        for i=1:length(app.deck.dealerHand.cards)
                            if app.deck.dealerHand.cards(i).numVal == "11"
                                app.deck.dealerHand.cards(i).numVal = "1";
                                total = app.sumDealer(app.deck);
                                break
                            end
                        end
                        total = app.sumDealer(app.deck);
                    end
                end
                total = app.sumDealer(app.deck);
            end
        end
        
        function cardImage = cardDeal(app, deck, xpos, number)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(number).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(number).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end

        function cardImage = newCard(app, deck, xpos)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function cardImage = dealerDeal(app, deck, xpos2, number)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos2 349 90 112];
            if app.deck.dealerHand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.dealerHand.cards(number).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.dealerHand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.dealerHand.cards(number).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function cardImage = dealerCard(app, deck, xpos2)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos2 349 90 112];
            if app.deck.dealerHand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.dealerHand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.dealerHand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.dealerHand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
        
        function flipDealerHand(app, deck)
            app.deck.dealerHand.cards(1).privacy = 1;
            app.deck.dealerHand.cards(2).privacy = 1;
            app.deck.dealerHand.cards(1).imageApp = dealerDeal(app, app.deck, 347, 1);
            app.deck.dealerHand.cards(2).imageApp = dealerDeal(app, app.deck, 310, 2);
        end
        
        function compareValues(app, deck)
            player = app.sumHand(app.deck);
            dealer = app.sumDealer(app.deck);
            if player > dealer
                app.winScreen
            elseif player == dealer
                app.tieScreen
            else
                app.loseScreen
            end
        end
        
        function winScreen(app)
            app.YouWonLabel.Visible="on"; %Bring up Win Message
            app.BalanceEditField.Value = app.BalanceEditField.Value + 2 * app.BetEditField.Value; %add money won to balance
        end
        function tieScreen(app)
            app.flipDealerHand
            app.YouTiedLabel.Visible="on"; %Bring up Loss Message
            app.BalanceEditField.Value = app.BalanceEditField.Value + app.BetEditField.Value;
        end
        function loseScreen(app)
            app.YouLostLabel.Visible="on"; %Bring up Loss Message
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            import pkg.Deck.*
            import pkg.Card.*
            import pkg.Hand.*
            import pkg.DiscardPile.*
            app.BalanceEditField.Value=500;
        end

        % Button pushed function: DEALButton
        function dealButton(app, event)
            app.deck = Deck;
            app.DEALButton.Visible = 'off';
            app.DEALButton.Enable = 'off';
            app.deck.p1Hand.cards(1).imageApp = cardDeal(app, app.deck, 347, 1);
            app.deck.p1Hand.cards(2).imageApp = cardDeal(app, app.deck, 310, 2);
            app.deck.dealerHand.cards(1).imageApp = dealerDeal(app, app.deck, 347, 1);
            app.deck.dealerHand.cards(2).imageApp = dealerDeal(app, app.deck, 310, 2);
            app.HitButton.Enable = 'on';
            app.StandButton.Enable = 'on';
            app.BalanceEditField.Editable = "off";
            app.BetEditField.Editable="off";
            app.BalanceEditField.Value=app.BalanceEditField.Value-app.BetEditField.Value;
            app.check21(app.deck)
        end

        % Button pushed function: HitButton
        function hitMe(app, event)
            app.deck.p1Hand.cards = [app.deck.p1Hand.cards, app.deck.cards(1)];
            app.deck.p1Hand.cards(end).privacy = 1;
            app.deck.cards(1) = [];
            app.xpos = app.xpos - 37;
            app.deck.p1Hand.cards(end).imageApp = newCard(app, app.deck, app.xpos);
            app.check21(app.deck);
            
        end

        % Button pushed function: StandButton
        function standMe(app, event)
            %app.HitButton.Enable = 'off';
            %app.StandButton.Enable = 'off';
            app.flipDealerHand(app.deck)
            app.check21(app.deck)
            app.dealerDraw(app.deck)
            app.compareValues(app.deck)
        end

        % Value changed function: BetEditField
        function ChangingBet(app, event)
            value = app.BetEditField.Value
            if (app.BetEditField.Value > app.BalanceEditField.Value) || (app.BetEditField.Value <= 0)
                app.DEALButton.Enable = 'off';
            else
                app.DEALButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @hitMe, true);
            app.HitButton.Enable = 'off';
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @standMe, true);
            app.StandButton.Enable = 'off';
            app.StandButton.Text = 'Stand';
            app.DeckBack = uiimage(app.UIFigure);
            app.DeckBack.ImageSource = 'back.jpg';
            app.DEALButton = uibutton(app.UIFigure, 'push');
            app.DEALButton.ButtonPushedFcn = createCallbackFcn(app, @dealButton, true);
            app.DEALButton.Text = 'DEAL';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetEditFieldLabel = uilabel(app.UIFigure);
            app.BetEditFieldLabel.Text = 'Bet';
            app.BetEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetEditField.ValueChangedFcn = createCallbackFcn(app, @ChangingBet, true);
            app.YouWonLabel = uilabel(app.UIFigure);
            app.YouWonLabel.Visible = 'off';
            app.YouWonLabel.Text = 'You Won!!!';
            app.YouLostLabel = uilabel(app.UIFigure);
            app.YouLostLabel.Visible = 'off';
            app.YouLostLabel.Text = 'You Lost :(';
            app.OutofMoneyLabel = uilabel(app.UIFigure);
            app.OutofMoneyLabel.Visible = 'off';
            app.OutofMoneyLabel.Text = 'Out of Money!!';
            app.YouTiedLabel = uilabel(app.UIFigure);
            app.YouTiedLabel.Visible = 'off';
            app.YouTiedLabel.Text = 'You Tied...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end