classdef palace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Submit                     matlab.ui.control.Button
        cardDeckImage              matlab.ui.control.Image
        PileImage                  matlab.ui.control.Image
        Player2HandListBoxLabel    matlab.ui.control.Label
        p2Hand                     matlab.ui.control.ListBox
        EndTurn                    matlab.ui.control.Button
        Player1HandLabel           matlab.ui.control.Label
        p1Hand                     matlab.ui.control.ListBox
        Player1TurnEditFieldLabel  matlab.ui.control.Label
        player1Turn                matlab.ui.control.NumericEditField
        Player2TurnEditFieldLabel  matlab.ui.control.Label
        player2Turn                matlab.ui.control.NumericEditField
        DeckLabel                  matlab.ui.control.Label
        cardDeck                   matlab.ui.control.NumericEditField
        p1Item1                    matlab.ui.control.Image
        p1Item2                    matlab.ui.control.Image
        p1Item3                    matlab.ui.control.Image
        p1Item4                    matlab.ui.control.Image
        EditFieldLabel             matlab.ui.control.Label
        Pile                       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function yes(app)
           import pkg.Deck.*
           app.player1Turn.Value = 1
           app.player2Turn.Value = 0
               deck = Deck;
           app.p1Hand.Items = deck.p1Hand;
           app.p2Hand.Items = deck.p2Hand;
           
        end

        % Button pushed function: Submit
        function submit(app, event)
            if app.player1Turn.Value == 1
            app.cardDeck.Value = [app.cardDeck.Value Deck.p1Hand]
            else
           app.cardDeck.Value = [app.cardDeck.Value Deck.p2Hand]
                
            end
        end

        % Button pushed function: EndTurn
        function EndTurnPressed(app, event)
            if app.player1Turn.Value == 1
                app.player1Turn.Value = 0
                app.player2Turn.Value = 1
            else
                app.player2Turn.Value = 0
                app.player1Turn.Value = 1
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Submit = uibutton(app.UIFigure, 'push');
            app.Submit.ButtonPushedFcn = createCallbackFcn(app, @submit, true);
            app.Submit.Text = {'Submit'; ''};
            app.cardDeckImage = uiimage(app.UIFigure);
            app.cardDeckImage.ImageSource = 'card back orange.png';
            app.PileImage = uiimage(app.UIFigure);
            app.PileImage.ImageSource = 'download.png';
            app.EndTurn = uibutton(app.UIFigure, 'push');
            app.EndTurn.ButtonPushedFcn = createCallbackFcn(app, @EndTurnPressed, true);
            app.EndTurn.Text = {'End Turn'; ''};
            app.Player1HandLabel = uilabel(app.UIFigure);
            app.Player1HandLabel.Text = 'Player 1 Hand';
            app.p1Hand = uilistbox(app.UIFigure);
            app.p1Hand.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', ''};
            app.p1Hand.Multiselect = 'on';
            app.p1Hand.Value = {'Item 1'};
            app.Player2HandListBoxLabel = uilabel(app.UIFigure);
            app.Player2HandListBoxLabel.Text = 'Player 2 Hand';
            app.p2Hand = uilistbox(app.UIFigure);
            app.Player1TurnEditFieldLabel = uilabel(app.UIFigure);
            app.Player1TurnEditFieldLabel.Text = 'Player 1 Turn';
            app.player1Turn = uieditfield(app.UIFigure, 'numeric');
            app.Player2TurnEditFieldLabel = uilabel(app.UIFigure);
            app.Player2TurnEditFieldLabel.Text = 'Player 2 Turn';
            app.player2Turn = uieditfield(app.UIFigure, 'numeric');
            app.DeckLabel = uilabel(app.UIFigure);
            app.DeckLabel.Text = 'Deck';
            app.cardDeck = uieditfield(app.UIFigure, 'numeric');
            app.p1Item1 = uiimage(app.UIFigure);
            app.p1Item2 = uiimage(app.UIFigure);
            app.p1Item3 = uiimage(app.UIFigure);
            app.p1Item4 = uiimage(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Pile = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = palace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @yes)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end